classdef ROIBuilderAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIBuilderUIFigure            matlab.ui.Figure
        MaxgoodSDSmmEditField         matlab.ui.control.NumericEditField
        MaxgoodSDSmmEditField_2Label  matlab.ui.control.Label
        ViewButtonGroup               matlab.ui.container.ButtonGroup
        ShowParcelsButton             matlab.ui.control.RadioButton
        ShowROIButton                 matlab.ui.control.RadioButton
        ClearROIButton                matlab.ui.control.Button
        MirrorinclradiusmmEditField   matlab.ui.control.NumericEditField
        MirrorinclradiusmmEditFieldLabel  matlab.ui.control.Label
        MirrorROIButton               matlab.ui.control.Button
        SaveROIButton                 matlab.ui.control.Button
        BrainpainterPanel             matlab.ui.container.Panel
        BrushDiametermmSliderLabel_2  matlab.ui.control.Label
        EraseButton                   matlab.ui.control.Button
        PaintButton                   matlab.ui.control.Button
        BrushDiametermmSlider         matlab.ui.control.Slider
        BrushDiametermmSliderLabel    matlab.ui.control.Label
        ParcelselectorPanel           matlab.ui.container.Panel
        REMOVESelectedfigButton       matlab.ui.control.Button
        ADDSelectedfigButton          matlab.ui.control.Button
        ParcelsincludedListBox        matlab.ui.control.ListBox
        ParcelsincludedListBoxLabel   matlab.ui.control.Label
        ParcelsallListBox             matlab.ui.control.ListBox
        ParcelsallListBoxLabel        matlab.ui.control.Label
        REMOVESelectedlistButton      matlab.ui.control.Button
        ADDSelectedlistButton         matlab.ui.control.Button
        ROIBuilderLabel               matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        RadiusmmEditField             matlab.ui.control.NumericEditField
        RadiusmmEditField_2Label      matlab.ui.control.Label
        CurrentROIorMNIText           matlab.ui.control.EditField
        DropDown                      matlab.ui.control.DropDown
        SelectParcellationDropDown    matlab.ui.control.DropDown
        SelectParcellationDropDownLabel  matlab.ui.control.Label
        SelectROIorCoordinatesButton  matlab.ui.control.Button
        HeadModelPathText             matlab.ui.control.EditField
        SelectHeadModelButton         matlab.ui.control.Button
        ROIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        repoPath = fileparts(mfilename('fullpath'));
        pathnameHeadModel; % Head model directory 
        pathnameROI;
        pathnameMSHS; % Actual head model mshs pathname
        pathnameMNI; % MNI coordinates directory
        gmSurfaceMesh;
        MNIcenters % Spheres centers
        nnode_ind; % Indexes calculated for each MNI coordinate
        ROI = struct('gmNodeList',[],'solutionSpaceNodeList',[]);
        ROIvParcelsFlag = 1;
        ParcellationNumber = 1;
        BrushDiameter = 10;
        currViewAngROI;
        save_menu; % Display for saving calculated coordinates
        tmp_savedfile; % Saved coordinates name
        tmp_savedext; % Saved coordinates extension
    end
    
    
    methods (Access = private)
        function results = updateAll(app)
            updateVariables(app)
            updateFigures(app)
        end
         
        function results = updateVariables(app)
            % Update all input components
            if ~isempty(app.pathnameHeadModel)
                [~,tmp,ext] = fileparts(app.pathnameHeadModel);
                app.HeadModelPathText.Value = [tmp];
            else
                app.HeadModelPathText.Value = '';
            end
            
            if ~isempty(app.pathnameROI)
                [~,tmp,ext] = fileparts(app.pathnameROI);
                app.CurrentROIorMNIText.Value = [tmp];
                app.ROI = loadROI(app.pathnameROI);
            else
                app.CurrentROIorMNIText.Value = '';
            end
            
            % Update mshs pathname
            if ~isempty(app.pathnameHeadModel)
                app.pathnameMSHS = getMSHSpath(app.pathnameHeadModel);
                % Update gmSurfaceMesh
                tmp = load(app.pathnameMSHS,'gmSurfaceMesh','-mat');
                app.gmSurfaceMesh = tmp.gmSurfaceMesh;
            end

            % Update parcellation dropdown
            if isfield(app.gmSurfaceMesh,'parcellation')
                nParcellations = size(app.gmSurfaceMesh.parcellation);
                for i = 1:nParcellations
                    app.SelectParcellationDropDown.Items{i} = app.gmSurfaceMesh.parcellation(i).name;
                end
                app.SelectParcellationDropDown.Value = app.SelectParcellationDropDown.Items{1};
                SelectParcellationDropDownValueChanged(app);
            end
            
        end
        
        function results = updateFigures(app)
            % Update ROI plot
            app.currViewAngROI = app.ROIAxes.View;
            if app.ROIvParcelsFlag
                % Update ROI plot
                cla(app.ROIAxes);
                if ~isempty(app.ROI) && ~isempty(app.gmSurfaceMesh)
                    plotROI(app.gmSurfaceMesh,app.ROI,'hAxes',app.ROIAxes,'view',app.currViewAngROI);
                end
            elseif app.ROIvParcelsFlag==0
                % Update plot with parcels
                cla(app.ROIAxes);
                if ~isempty(app.gmSurfaceMesh)
                    if isfield(app.gmSurfaceMesh,'parcellation')
                        plotParcellation(app.gmSurfaceMesh,app.ParcellationNumber,'hAxes',app.ROIAxes,'view',app.currViewAngROI);
                    end
                end
                
            end
        end
        
        % Function for creating the first column of the ROI txt file
        function firstColumnROI(app)
            nodes = app.gmSurfaceMesh.node;
            radius = app.RadiusmmEditField.Value; % Radius from input
            distcnSelected = [];
            for i = 1:size(app.MNIcenters, 1) % For each mni center
                distcn = zeros(size(nodes, 1), 1); % New distances vector
                for j = 1:size(nodes, 1) % For each node
                    % Calulation of 3D distances
                    distx = abs(app.MNIcenters(i, 1) - nodes(j, 1));
                    disty = abs(app.MNIcenters(i, 2) - nodes(j, 2));
                    distz = abs(app.MNIcenters(i, 3) - nodes(j, 3));
                    norm = sqrt((distx)^2 + (disty)^2 + (distz)^2);
                    distcn(j) = norm; 
                end
                % Nodes indexes < radius
                ind = find(distcn < radius); 

                % Indices in the current cell
                distcnSelected = [distcnSelected; ind];
            end
            distcnSelected = [distcnSelected; app.nnode_ind];
            distcnSelected = unique(distcnSelected);
            app.ROI.gmNodeList = distcnSelected;

            updateFigures(app)
        end

        % Saving coordinates functions
        function saveCoordinates(app)
            % Check if user entered a chosen file name
            if isempty(app.tmp_savedfile)
                disp('Operation canceled. No files will be saved.');
                return;
            end

            % Full name of the file
            fileName = [app.tmp_savedfile.Value, app.tmp_savedext.Value];
            MNIdirectoryPath = fullfile(app.pathnameHeadModel, 'MNI coordinates', fileName);

            % Saving file based on extension
            switch app.tmp_savedext.Value
                case '.txt'
                    writematrix(app.MNIcenters, MNIdirectoryPath, 'Delimiter', '\t'); % Saved as .txt
                case '.csv'
                    writematrix(app.MNIcenters, MNIdirectoryPath); % Saved as .csv
            end

            delete(app.save_menu); % Сlosing the saving menu
        end

        % Cancel button
        function cancelSaving(app)
            disp('Saving cancelled');
            delete(app.save_menu); % Closing the saving menu
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pathnameROI, pathnameHeadModel)
            if exist('pathnameROI','var')
                if ~isempty(pathnameROI)
                    app.pathnameROI = pathnameROI;
                end
            end
            if exist('pathnameHeadModel','var')
                if ~isempty(pathnameHeadModel)
                    app.pathnameHeadModel = pathnameHeadModel;
                end
            end
            
            updateAll(app)

        end

        % Button pushed function: SelectHeadModelButton
        function SelectHeadModelButtonPushed(app, event)
            [filename, pathname, ~] = uigetfile(fullfile(app.repoPath, '*.mshs'), 'Select .mshs file');
            if filename==0
                return
            end
            fullpath = [pathname filename];
            [pathname,filename,ext] = fileparts(fullpath);
            if isunix % check if the OS is MacOS or Linux
                pathnameHeadModeltmp = [pathname '/' filename];
            elseif ispc % check if is the OS is Microsoft
                pathnameHeadModeltmp = [pathname '\' filename];
            end
            
            % Loading of .mshs file
            mshstmp = load([pathnameHeadModeltmp ext],'-mat');
            
            makeSolutionSpaceFlag = 0;

            headModelsDir = fullfile(app.repoPath, 'HeadModels', filename);
            if ~exist(headModelsDir, 'dir')
                mkdir(headModelsDir);
                save(pathnameHeadModeltmp, '-struct', 'mshstmp');
                app.pathnameHeadModel = headModelsDir;
        
                % Also make ROI direcotry
                mkdir(fullfile(headModelsDir, 'ROIs'));
        
                % Make scalpPos.txt (solution space)
                makeSolutionSpaceFlag = 1;

            elseif ~strcmpi(fullfile(app.repoPath, 'HeadModels', filename), pathnameHeadModeltmp)
                selection = uiconfirm(app.ROIBuilderUIFigure, ...
                'A head model with this name already exists in ArrayDesigner', ...
                'Head Model', ...
                'Options', {'Use Existing', 'Overwrite Existing'}, ...
                'DefaultOption', 1, ...
                'Icon', 'warning');
        
                    if strcmpi(selection, 'Use Existing')
                        app.pathnameHeadModel = fullfile(app.repoPath, 'HeadModels', filename);
                        if ~exist(fullfile(app.repoPath, 'HeadModels', filename, 'Utils', 'scalpSolutionSpace_10_2p5.txt'), 'file')
                            makeSolutionSpaceFlag = 1;
                        end
                    elseif strcmpi(selection, 'Overwrite Existing')
                        save(fullfile(app.repoPath, 'HeadModels', [filename ext]), '-struct', 'mshstmp');
                        app.pathnameHeadModel = fullfile(app.repoPath, 'HeadModels', filename);
                        if ~exist(fullfile(app.repoPath, 'HeadModels', filename, 'Utils', 'scalpSolutionSpace_10_2p5.txt'), 'file')
                            makeSolutionSpaceFlag = 1;
                        end
                    end

            else %Headmodel is existing one selected from HeadModels folder
                % Specify pathnameHeadModel as that selected. Double check solution space exists
                app.pathnameHeadModel = fullfile(app.repoPath, 'HeadModels', filename);
                if ~exist(fullfile(app.repoPath, 'HeadModels', filename, 'Utils', 'scalpSolutionSpace_10_2p5.txt'), 'file')
                    makeSolutionSpaceFlag = 1;
                end
            end

            % Update text field
            app.HeadModelPathText.Value = app.pathnameHeadModel;
             
            % Update mshs pathname
            app.pathnameMSHS = fullfile(app.pathnameHeadModel, [filename ext]);
            
            % Load gmSurfaceMesh
            app.gmSurfaceMesh = mshstmp.gmSurfaceMesh;
            
            % If needed make solution space
            if makeSolutionSpaceFlag
                selection = uiconfirm(app.ROIBuilderUIFigure,'The selected head model has no defined scalp solution space, would you like to compute this now?', ...
                    'Solution space', ...
                    'Options',{'Yes','Cancel'}, ...
                    'DefaultOption',1,...
                    'Icon','warning');
                if strcmpi(selection,'Yes')
                    % Create solution space - scalpSolutionSpace_10_2p5.txt
                    getSolutionSpace(app.pathnameHeadModel,app.pathnameMSHS);
                    
                    % Write gmPos.txt
                    writeGMPos(app.gmSurfaceMesh,app.pathnameHeadModel);
                    
                end
            end
            
            % Clear ROI text field
            app.CurrentROIorMNIText.Value = '';
            app.pathnameROI = '';
            app.ROI = struct('gmNodeList',[],'scalpPosList',[]);
            
            % Update axes
            updateFigures(app);
            
            % Update parcellation dropdown
            if isfield(app.gmSurfaceMesh,'parcellation')
                nParcellations = size(app.gmSurfaceMesh.parcellation, 2);
                for i = 1:nParcellations
                    app.SelectParcellationDropDown.Items{i} = app.gmSurfaceMesh.parcellation(i).name;
                end
                app.SelectParcellationDropDown.Value = app.SelectParcellationDropDown.Items{1};
                SelectParcellationDropDownValueChanged(app);
            end
            
            % Parcellations cleaning after changing Head Model
            if size(app.gmSurfaceMesh.node, 2) == 3 % If it's the asymmetric atlas
                app.ParcelsallListBox.Items = {};
            end
   
        end

        % Button pushed function: SelectROIorCoordinatesButton
        function SelectROIorCoordinatesButtonPushed(app, event)
            switch app.DropDown.Value
                % User has selected an already calculated ROI
                case 'Existing ROI' 
                    [filename, pathname, ~] = uigetfile(fullfile(app.repoPath, 'HeadModels', '*.txt'),'Select ROI text file (should be within the relevant HeadModels directory in your Array Designer folder)');
                    if filename==0
                        return
                    end
                    fullpath = [pathname filename];
                    [pathname,filename,ext] = fileparts(fullpath);
                    app.pathnameROI = fullfile(pathname,[filename ext]);
                    app.ROI = loadROI(app.pathnameROI);
                    app.CurrentROIorMNIText.Value = [filename ext];

                    % Determine headmodel from ROI location (if possible)
                    if strcmpi(pathname(end-3:end),'ROIs') % So far so good
                        pathnameHeadModeltmp = fileparts(pathname);
                        pathnameMSHStmp = getMSHSpath(pathnameHeadModeltmp);
                        if exist(pathnameMSHStmp,'file')
                            app.pathnameHeadModel = pathnameHeadModeltmp;
                            app.pathnameMSHS = pathnameMSHStmp;
                            mshstmp = load(app.pathnameMSHS,'-mat');
                        else
                            uialert(app.ROIBuilderUIFigure,'The head model associated with the selected ROI cannot be found. Please place your ROI .txt file in the /ROIs subdirectory within the associated head model directory within /HeadModels, then reload.','Missing Head Model');
                            return
                        end
                    else
                        uialert(app.ROIBuilderUIFigure,'The head model associated with the selected ROI cannot be found. Please place your ROI .txt file in the /ROIs subdirectory within the associated head model directory within /HeadModels, then reload.','Missing Head Model');
                        return
                    end
        
                    % Update text field
                    app.HeadModelPathText.Value = app.pathnameHeadModel;
        
                    % Load gmSurfaceMesh
                    app.gmSurfaceMesh = mshstmp.gmSurfaceMesh;
        
                    % Update axes
                    updateFigures(app);
        
                    % Update parcellation dropdown
                    if isfield(app.gmSurfaceMesh,'parcellation')
                        nParcellations = size(app.gmSurfaceMesh.parcellation);
                        for i = 1:nParcellations
                            app.SelectParcellationDropDown.Items{i} = app.gmSurfaceMesh.parcellation(i).name;
                        end
                        app.SelectParcellationDropDown.Value = app.SelectParcellationDropDown.Items{1};
                        SelectParcellationDropDownValueChanged(app);
                    end

                % User has selected a file with xyz coordinates
                case 'MNI coordinates' 
                    [filename, pathname, ~] = uigetfile(fullfile(app.repoPath, 'ArrayDesigner', '*.txt;*.csv'), 'Select MNI coordinates');
                    if filename == 0
                        return
                    end
                    fullpath = [pathname filename];
                    [pathname,filename,ext] = fileparts(fullpath);
                    app.pathnameMNI = fullfile(pathname,[filename ext]);
                    app.CurrentROIorMNIText.Value = [filename ext];

                    % Update text field
                    app.HeadModelPathText.Value = app.pathnameHeadModel;

                    % Nearest nodes for MNI coordinate
                    upldCoor = readmatrix(fullpath); % Add the xyz coordinate file in a matrix
                    if size(upldCoor,1)<size(upldCoor,2) % If is 3xM
                        upldCoor = upldCoor';
                    end
                    
                    nodi = app.gmSurfaceMesh.node;

                    % Check that the file contains transformd coordinates
                    distcnmin = zeros(size(upldCoor, 1), 1);
                    for i = 1:size(upldCoor, 1) % For each uploaded coordinate
                        distcn = zeros(size(nodi, 1), 1); % Initialize a vector for distances
                        for j = 1:size(nodi, 1) % For each node
                            % Calculation of 3D distances
                            distx = abs(upldCoor(i, 1) - nodi(j, 1));
                            disty = abs(upldCoor(i, 2) - nodi(j, 2));
                            distz = abs(upldCoor(i, 3) - nodi(j, 3));
                            norm = sqrt((distx)^2 + (disty)^2 + (distz)^2);
                            distcn(j) = norm; 
                        end
                        distcnmin(i) = min(distcn);
                    end

                    % File with already transformed coordinates
                    if isempty(find(distcnmin > 1)) % If there are no distances that do not exceed the threshold of 1mm 
                                                    % (each coordinate is almost coincident with the point on gmSurfaceMesh)
                        app.MNIcenters = zeros(size(upldCoor,1),size(upldCoor,2)); % Empty matrix initialization where the centers 
                                                                                   %will go to calculate the spheres
                        for i= 1:size(upldCoor,1)
                            for j= 1:size(upldCoor,2)
                                app.MNIcenters(i,j) = upldCoor(i,j);
                            end
                        end
                        firstColumnROI(app) % Calculation of column 1 of the ROI file

                    % File with coordinates to be transformed
                    else 
                        app.MNIcenters = zeros(size(upldCoor,1),size(upldCoor,2)); % Empty matrix initialization where the centers 
                                                                                   % will go to calculate the spheres
                        for i=1:size(upldCoor,1) % For each xyz value, find the corresponding center
                            puntoMNI = upldCoor(i,:);
                            [nnode, app.nnode_ind(i,1), ~] = DOTHUB_nearestNode(puntoMNI, nodi); % Find the nearest node
                            app.MNIcenters(i, :) = nnode; % Add the closest node  to the matrix
                        end
                       
                        % Check if the new points are too distant from the position in the uploaded file
                        exceedsThreshold =[];
                        for i = 1:size(app.MNIcenters,1)
                            distx = abs(app.MNIcenters(i,1) - upldCoor(i,1));
                            disty = abs(app.MNIcenters(i,2) - upldCoor(i,2));
                            distz = abs(app.MNIcenters(i,3) - upldCoor(i,3));
                            norm = sqrt((distx)^2 + (disty)^2 + (distz)^2);
                            dist(i,:) = [norm, distx, disty, distz];
    
                            if norm > 10 % If the distance of each point exceed 1cm
                                exceedsThreshold = [exceedsThreshold; i, norm, upldCoor(i, :), distx, disty, distz]; % Add index and coordinates to the results vector
                            end
                        end
                        
                        if ~isempty(exceedsThreshold) % Check if some coordinates are too far apart
                            disp('The following points exceed the threshold of 10 mm! ');
                            for j = 1:size(exceedsThreshold, 1)                           
                                % Check if one or more coordinates exceed the threshold
                                exceedingCoordinates = [];
                                if exceedsThreshold(j,6) >= 10
                                    exceedingCoordinates = [exceedingCoordinates, "X"];
                                end
                                if exceedsThreshold(j,7) >= 10
                                    exceedingCoordinates = [exceedingCoordinates, "Y"];
                                end
                                if exceedsThreshold(j,8) >= 10
                                    exceedingCoordinates = [exceedingCoordinates, "Z"];
                                end
                        
                                if ~isempty(exceedingCoordinates) % If at least one coordinate exceeds threshold
                                    fprintf('Index: %d | Coordinate (x, y, z): (%.2f, %.2f, %.2f) | 3D distance: %.2f mm \n', ...
                                        exceedsThreshold(j, 1), exceedsThreshold(j, 3), exceedsThreshold(j, 4), exceedsThreshold(j, 5), exceedsThreshold(j, 2));
                                    fprintf('Coordinates exceeding the 10 mm threshold: %s\n', strjoin(exceedingCoordinates, ', '));
    
                                else  % If threshold is exceeded but none of the coordinates exceed it
                                    fprintf(['Index: %d | Coordinate (x, y, z): (%.2f, %.2f, %.2f), 3D distance 3D: %.2f mm, \n', ...
                                        'No specific coordinate exceeds the threshold of 10 mm. Provide a better point. \n'], ...
                                       exceedsThreshold(j, 1), exceedsThreshold(j, 3), exceedsThreshold(j, 4), exceedsThreshold(j, 5), exceedsThreshold(j, 2));
                                end
                                fprintf('---\n');
                            end 
                        else
                            % File saving and plotting
                            % Window creation
                            app.save_menu = uifigure('Name', 'Save File', 'Position', [500, 300, 250, 100], 'Resize', 'off');
                            
                            % Creating a grid layout with 3 rows and 2 columns
                            grid = uigridlayout(app.save_menu, [3, 2]);
                            grid.RowHeight = {'fit', 'fit', 'fit'};
                            grid.ColumnWidth = {'6.5x', '9x'};
                            grid.Padding = [10, 10, 10, 10];
                            grid.RowSpacing = 5;
                            grid.ColumnSpacing = 10;
                            
                            % File name label
                            nameLabel = uilabel(grid, 'Text', 'Insert file name:', ...
                                'HorizontalAlignment', 'center', ...
                                'FontSize', 12);
                            nameLabel.Layout.Row = 1; nameLabel.Layout.Column = 1;
                            
                            % Filename
                            app.tmp_savedfile = uieditfield(grid, 'text', ...
                                'Value', 'MNI_salvate', ...
                                'HorizontalAlignment', 'left', ...
                                'FontSize', 11);
                             app.tmp_savedfile.Layout.Row = 1;  app.tmp_savedfile.Layout.Column = 2;
                            
                            % Extension label
                            extLabel = uilabel(grid, 'Text', 'Choose format:', ...
                                'HorizontalAlignment', 'center', ...
                                'FontSize', 12);
                            extLabel.Layout.Row = 2; extLabel.Layout.Column = 1;
                            
                            % Extension dropdown
                            app.tmp_savedext = uidropdown(grid, ...
                                'Items', {'.txt', '.csv'}, ...
                                'Value', '.txt', ...
                                'FontSize', 11);
                            app.tmp_savedext.Layout.Row = 2; app.tmp_savedext.Layout.Column = 2;
                            
                            % Save button
                            saveButton = uibutton(grid, ...
                                'Text', 'Save', ...
                                'ButtonPushedFcn', @(btn, event) saveCoordinates(app), ...
                                'FontSize', 12);
                            saveButton.Layout.Row = 3; saveButton.Layout.Column = 1;
                            
                            % Cancel button
                            cancelButton = uibutton(grid, ...
                                'Text', 'Cancel', ...
                                'ButtonPushedFcn', @(btn, event) cancelSaving(app), ...
                                'FontSize', 12);
                            cancelButton.Layout.Row = 3; cancelButton.Layout.Column = 2;
    
                            firstColumnROI(app)
                        end
                    end
            
            end
            
        end

        % Value changed function: RadiusmmEditField
        function RadiusmmEditFieldValueChanged(app, event)
            app.ROI = struct('gmNodeList',[],'scalpPosList',[]);
            updateFigures(app);
            firstColumnROI(app)
            
        end

        % Value changed function: SelectParcellationDropDown
        function SelectParcellationDropDownValueChanged(app, event)
            value = app.SelectParcellationDropDown.Value;
            % Update parcelsavailable list
            ind = find(strcmp(app.SelectParcellationDropDown.Items,app.SelectParcellationDropDown.Value));
            app.ParcellationNumber = ind;
            app.ParcelsallListBox.Items = app.gmSurfaceMesh.parcellation(ind).labels;
        end

        % Button pushed function: ADDSelectedlistButton
        function ADDSelectedlistButtonPushed(app, event)
            currList = app.ParcelsincludedListBox.Items;
            added = app.ParcelsallListBox.Value;
                toAdd = {};
            count = 1;
            for i = 1:length(added)
                if ~any(ismember(app.ParcelsincludedListBox.Items,added{i}))
                    toAdd{count} = added{i};
                    %UPDATE ROI
                    parcelIndex = find(ismember(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).labels,added{i}));
                    nodes2add = find(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index==parcelIndex);
                    app.ROI.gmNodeList = unique([app.ROI.gmNodeList; nodes2add]);
                    count = count + 1;
                end
            end
            app.ParcelsincludedListBox.Items = [currList toAdd];
            
            app.ShowROIButton.Value = 1;
            app.ROIvParcelsFlag = 1;
            updateFigures(app)
            
        end

        % Button pushed function: REMOVESelectedlistButton
        function REMOVESelectedlistButtonPushed(app, event)
            currList = app.ParcelsincludedListBox.Items;
            toRem = app.ParcelsincludedListBox.Value;
            for i = 1:length(toRem)
                currList = currList(~ismember(currList,toRem{i}));
                % Update ROI
                parcelIndex = find(ismember(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).labels,toRem{i}));
                nodes2rem = find(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index==parcelIndex);
                app.ROI.gmNodeList = app.ROI.gmNodeList(~ismember(app.ROI.gmNodeList,nodes2rem));
            end
            app.ParcelsincludedListBox.Items = currList;
            
            app.ShowROIButton.Value = 1;
            app.ROIvParcelsFlag = 1;
            updateFigures(app)
            
        end

        % Selection changed function: ViewButtonGroup
        function ROIParcelToggle(app, event)
            selectedButton = app.ViewButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text,'Show Parcels')
                app.ROIvParcelsFlag = 0;
                updateFigures(app)
            elseif strcmpi(selectedButton.Text,'Show ROI')
                app.ROIvParcelsFlag = 1;
                updateFigures(app)
            end

        end

        % Button pushed function: ADDSelectedfigButton
        function ADDSelectedfigButtonPushed(app, event)
            dcObj = findall(app.ROIAxes,'Type','hggroup');
            if ~isempty(dcObj)
                pos = dcObj(1).Cursor.Position;
                % ###### Now find the parcel associated with this position and add
                % it to the ROI, then update figure. Do equivalent for
                % remove button. Also use same approach in brain painter
                % setting
                [~,posInd] = min(sum(abs(app.gmSurfaceMesh.node - repmat(pos,length(app.gmSurfaceMesh.node),1)),2));
                parcelIndex = app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index(posInd);
                nodes2add = find(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index==parcelIndex);
                app.ROI.gmNodeList = unique([app.ROI.gmNodeList; nodes2add]);
                
                %Add parcel to text list
                currList = app.ParcelsincludedListBox.Items;
                toAdd = app.gmSurfaceMesh.parcellation(app.ParcellationNumber).labels{parcelIndex};
                app.ParcelsincludedListBox.Items = [currList toAdd];
                
                app.ShowROIButton.Value = 1;
                app.ROIvParcelsFlag = 1;
                updateFigures(app)
                
            else
                msgbox('Select a point on the brain before attempting to add parcel');
            end
            
        end

        % Button pushed function: REMOVESelectedfigButton
        function REMOVESelectedfigButtonPushed(app, event)
            dcObj = findall(app.ROIAxes,'Type','hggroup');
            if ~isempty(dcObj)
                pos = dcObj(1).Cursor.Position;
                % ###### Now find the parcel associated with this position and add
                % it to the ROI, then update figure. Do equivalent for
                % remove button. Also use same approach in brain painter
                % setting
                [~,posInd] = min(sum(abs(app.gmSurfaceMesh.node - repmat(pos,length(app.gmSurfaceMesh.node),1)),2));
                parcelIndex = app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index(posInd);
                nodes2rem = find(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index==parcelIndex);
                app.ROI.gmNodeList = app.ROI.gmNodeList(~ismember(app.ROI.gmNodeList,nodes2rem));
                
                %remove from text lists
                currList = app.ParcelsincludedListBox.Items;
                toRem = app.gmSurfaceMesh.parcellation(app.ParcellationNumber).labels{parcelIndex};
                currList = currList(~ismember(currList,toRem));
                app.ParcelsincludedListBox.Items = currList;
                
                app.ShowROIButton.Value = 1;
                app.ROIvParcelsFlag = 1;
                updateFigures(app)
                
            else
                msgbox('Select a point on the brain before attempting to remove parcel','Select point!');
            end
            
        end

        % Value changed function: BrushDiametermmSlider
        function BrushDiametermmSliderValueChanged(app, event)
            value = app.BrushDiametermmSlider.Value;
            app.BrushDiameter = value;

        end

        % Button pushed function: PaintButton
        function PaintButtonPushed(app, event)
            dcObj = findall(app.ROIAxes,'Type','hggroup');
            if ~isempty(dcObj)
                pos = dcObj(1).Cursor.Position;
                % ###### Now find nodes within diameter/2 of pos
                rad = app.BrushDiameter/2;
                nodes2add = find(sqrt(sum((app.gmSurfaceMesh.node(:,1:3) - repmat(pos,length(app.gmSurfaceMesh.node),1)).^2,2)) <= rad);
                app.ROI.gmNodeList = unique([app.ROI.gmNodeList; nodes2add]);
                
                app.ShowROIButton.Value = 1;
                app.ROIvParcelsFlag = 1;
                updateFigures(app)
            else
                msgbox('Select a point on the brain to paint before hitting Add');
            end
            
        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)
            dcObj = findall(app.ROIAxes,'Type','hggroup');
            if ~isempty(dcObj)
                pos = dcObj(1).Cursor.Position;
                % ###### Now find nodes within diameter/2 of pos
                rad = app.BrushDiameter/2;
                nodes2rem = find(sqrt(sum((app.gmSurfaceMesh.node - repmat(pos,length(app.gmSurfaceMesh.node),1)).^2,2)) <= rad);
                app.ROI.gmNodeList = app.ROI.gmNodeList(~ismember(app.ROI.gmNodeList,nodes2rem));
                
                app.ShowROIButton.Value = 1;
                app.ROIvParcelsFlag = 1;
                updateFigures(app)
            else
                msgbox('Select a point on the brain to paint before hitting Erase');
            end
      
        end

        % Button pushed function: MirrorROIButton
        function MirrorROIButtonPushed(app, event)
            % Force current ROI to become bilaterally symmetric
            radius = app.MirrorinclradiusmmEditField.Value;
            currROIList = app.ROI.gmNodeList;
            currROI = app.gmSurfaceMesh.node(app.ROI.gmNodeList,1:3);
            currROIflip = currROI;
            currROIflip(:,1) = currROI(:,1)*-1; %Flip about x
            nCurrROI = length(currROI);
            nPosGM = length(app.gmSurfaceMesh.node);
            
            % Create distMAT
            distMat = zeros(nCurrROI*2,nPosGM);
            wb = waitbar(0,'Mirroring ROI...');
            for ii = 1:nCurrROI
                if exist('wb','var')
                    waitbar(ii/nCurrROI,wb);
                else
                    return
                end
                
                dists = sqrt(sum((app.gmSurfaceMesh.node(:,1:3) - repmat(currROIflip(ii,:),nPosGM,1)).^2,2));
                distMat(ii,:) = dists;
                % Include ipsilateral points to maintain size and pprevent 
                % hemisphere ROIs growing alterntely
                dists = sqrt(sum((app.gmSurfaceMesh.node(:,1:3) - repmat(currROI(ii,:),nPosGM,1)).^2,2));
                distMat(ii+nCurrROI,:) = dists;                
            end
            
            toAdd = find(any(distMat<=radius,1))';
            currROIList = [currROIList; toAdd];
            
            if exist('wb','var'); delete(wb); end
            
            app.ROI.gmNodeList = currROIList;
            figure(app.ROIBuilderUIFigure);
            updateFigures(app);           
            
            % wb = waitbar(0,'Mirroring ROI...');
            % for i = 1:length(currROI)
            %     tmpNode = currROI(i,:);
            %     tmpNode2 = tmpNode;
            %     tmpNode2(1) = -tmpNode(1);
            %     dists = sqrt(sum((app.gmSurfaceMesh.node(:,1:3) - repmat(tmpNode2,nPosGM,1)).^2,2));
            %     currROIList = [currROIList; find(dists<= radius)];
            %     %Do the same for ipsilateral nodes to keep selected regions
            %     %the same diameter (prevent them growing unevenly)
            %     dists = sqrt(sum((app.gmSurfaceMesh.node(:,1:3) - repmat(tmpNode,nPosGM,1)).^2,2));
            %     currROIList = [currROIList; find(dists<= radius)];
            %     if exist('wb','var')
            %         waitbar(i/nCurrROI,wb);
            %     else
            %         return
            %     end
            % end
            % if exist('wb','var'); delete(wb); end
            % app.ROI.gmNodeList = currROIList;
            % figure(app.ROIBuilderUIFigure);
            % updateFigures(app);
            
        end

        % Button pushed function: SaveROIButton
        function SaveROIButtonPushed(app, event)
            [filename,pathname,ext] = uiputfile([app.pathnameHeadModel '/ROIs/*.txt'],'Save ROI .txt file');
            if filename==0
                return
            end
            fullpath = [pathname filename ext];
            
            % Select solution space and create subset positions
            % TO DO - make it possible to select solutions space (10-5 or 10-2p5 etc)
            if ~exist([app.pathnameHeadModel '/Utils/scalpSolutionSpace_10_2p5.txt'],"file")
                selection = uiconfirm(app.ROIBuilderUIFigure,'The selected head model has no defined scalp solution space, would you like to compute this now?','Solution space','Options',{'Yes','Cancel'},'DefaultOption',1,...
                    'Icon','warning');
                if strcmpi(selection,'Yes')
                    % Create solution space - scalpSolutionSpace_10_2p5.txt
                    getTen2p5(app.pathnameHeadModel,app.pathnameMSHS);
                    
                    % Write gmPos.txt
                    writeGMPos(app.gmSurfaceMesh,app.pathnameHeadModel);
                end
            end
            
            % Create solution space subset associated with this ROI
            scalpSolSpace = load([app.pathnameHeadModel '/Utils/scalpSolutionSpace_10_2p5.txt']);
            maxRho = app.MaxgoodSDSmmEditField.Value; % user input for MaxRho
            [~, solSpaceSubsetInd] = getScalpSolSpaceSubset(app.gmSurfaceMesh,scalpSolSpace,app.ROI.gmNodeList,maxRho);
            app.ROI.solutionSpaceNodeList = solSpaceSubsetInd;
            
            writeROI(app.ROI,fullpath);
            app.pathnameROI = fullpath;
            app.CurrentROIorMNIText.Value = app.pathnameROI;

        end

        % Button pushed function: ClearROIButton
        function ClearROIButtonPushed(app, event)
            app.ROI.gmNodeList = [];
            app.ROI.solutionSpaceNodeList = [];
            app.pathnameROI = [];
            updateAll(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIBuilderUIFigure = uifigure('Visible', 'off');
            app.ROIBuilderUIFigure.Name = 'ROI Builder';
            app.ROIAxes = uiaxes(app.ROIBuilderUIFigure);
            xlabel(app.ROIAxes, 'X (mm)')
            ylabel(app.ROIAxes, 'Y (mm)')
            app.Panel = uipanel(app.ROIBuilderUIFigure);
            app.SelectHeadModelButton = uibutton(app.Panel, 'push');
            app.SelectHeadModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectHeadModelButtonPushed, true);
            app.SelectHeadModelButton.Text = 'Select Head Model';
            app.HeadModelPathText = uieditfield(app.Panel, 'text');
            app.HeadModelPathText.Editable = 'off';
            app.HeadModelPathText.Tooltip = {'Select the head model (.mshs file) in which you want to specify your ROI'};
            app.SelectROIorCoordinatesButton = uibutton(app.Panel, 'push');
            app.SelectROIorCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIorCoordinatesButtonPushed, true);
            app.SelectROIorCoordinatesButton.Text = 'Select ROI or Coordinates';
            app.SelectParcellationDropDownLabel = uilabel(app.Panel);
            app.SelectParcellationDropDownLabel.Text = 'Select Parcellation';
            app.SelectParcellationDropDown = uidropdown(app.Panel);
            app.SelectParcellationDropDown.Items = {''};
            app.SelectParcellationDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectParcellationDropDownValueChanged, true);
            app.SelectParcellationDropDown.Tooltip = {'Choose a parcellation from those present within the selected head model .gmSurfaceMesh'};
            app.SelectParcellationDropDown.Value = '';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Existing ROI', 'MNI coordinates'};
            app.DropDown.Value = 'Existing ROI';
            app.CurrentROIorMNIText = uieditfield(app.Panel, 'text');
            app.CurrentROIorMNIText.Editable = 'off';
            app.CurrentROIorMNIText.Tooltip = {'Select an exisiting ROI file to edit (.txt)'};
            app.RadiusmmEditField_2Label = uilabel(app.Panel);
            app.RadiusmmEditField_2Label.Text = 'Radius (mm)  ';
            app.RadiusmmEditField = uieditfield(app.Panel, 'numeric');
            app.RadiusmmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusmmEditFieldValueChanged, true);
            app.RadiusmmEditField.Value = 10;
            app.ROIBuilderLabel = uilabel(app.ROIBuilderUIFigure);
            app.ROIBuilderLabel.Text = 'ROI Builder';
            app.ParcelselectorPanel = uipanel(app.ROIBuilderUIFigure);
            app.ParcelselectorPanel.Title = 'Parcel selector';
            app.ADDSelectedlistButton = uibutton(app.ParcelselectorPanel, 'push');
            app.ADDSelectedlistButton.ButtonPushedFcn = createCallbackFcn(app, @ADDSelectedlistButtonPushed, true);
            app.ADDSelectedlistButton.Tooltip = {'Add the selected parcels to your ROI'};
            app.ADDSelectedlistButton.Text = 'ADD Selected (list) >';
            app.REMOVESelectedlistButton = uibutton(app.ParcelselectorPanel, 'push');
            app.REMOVESelectedlistButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVESelectedlistButtonPushed, true);
            app.REMOVESelectedlistButton.Tooltip = {'Remove the selected parcels from your ROI'};
            app.REMOVESelectedlistButton.Text = '< REMOVE Selected (list)';
            app.ParcelsallListBoxLabel = uilabel(app.ParcelselectorPanel);
            app.ParcelsallListBoxLabel.Text = 'Parcels (all) ';
            app.ParcelsallListBox = uilistbox(app.ParcelselectorPanel);
            app.ParcelsallListBox.Items = {''};
            app.ParcelsallListBox.Multiselect = 'on';
            app.ParcelsallListBox.Tooltip = {'Listed here are all the parcels in the seelcted parcellation'};
            app.ParcelsallListBox.Value = {};
            app.ParcelsincludedListBoxLabel = uilabel(app.ParcelselectorPanel);
            app.ParcelsincludedListBoxLabel.Text = 'Parcels (included)';
            app.ParcelsincludedListBox = uilistbox(app.ParcelselectorPanel);
            app.ParcelsincludedListBox.Items = {};
            app.ParcelsincludedListBox.Multiselect = 'on';
            app.ParcelsincludedListBox.Tooltip = {'Listed here are all the parcels you have selected as part fo your ROI'};
            app.ParcelsincludedListBox.Value = {};
            app.ADDSelectedfigButton = uibutton(app.ParcelselectorPanel, 'push');
            app.ADDSelectedfigButton.ButtonPushedFcn = createCallbackFcn(app, @ADDSelectedfigButtonPushed, true);
            app.ADDSelectedfigButton.Tooltip = {'Add the selected parcels to your ROI'};
            app.ADDSelectedfigButton.Text = 'ADD Selected (fig) >';
            app.REMOVESelectedfigButton = uibutton(app.ParcelselectorPanel, 'push');
            app.REMOVESelectedfigButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVESelectedfigButtonPushed, true);
            app.REMOVESelectedfigButton.Tooltip = {'Remove the selected parcels from your ROI'};
            app.REMOVESelectedfigButton.Text = '< REMOVE Selected (fig)';
            app.BrainpainterPanel = uipanel(app.ROIBuilderUIFigure);
            app.BrainpainterPanel.Title = 'Brain painter';
            app.BrushDiametermmSliderLabel = uilabel(app.BrainpainterPanel);
            app.BrushDiametermmSliderLabel.Text = 'Brush Diameter (mm)';
            app.BrushDiametermmSlider = uislider(app.BrainpainterPanel);
            app.BrushDiametermmSlider.Limits = [0 50];
            app.BrushDiametermmSlider.ValueChangedFcn = createCallbackFcn(app, @BrushDiametermmSliderValueChanged, true);
            app.BrushDiametermmSlider.Value = 10;
            app.PaintButton = uibutton(app.BrainpainterPanel, 'push');
            app.PaintButton.ButtonPushedFcn = createCallbackFcn(app, @PaintButtonPushed, true);
            app.PaintButton.Text = 'Paint';
            app.EraseButton = uibutton(app.BrainpainterPanel, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Text = 'Erase';
            app.BrushDiametermmSliderLabel_2 = uilabel(app.BrainpainterPanel);
            app.BrushDiametermmSliderLabel_2.Text = 'Brush stroke:';
            app.SaveROIButton = uibutton(app.ROIBuilderUIFigure, 'push');
            app.SaveROIButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIButtonPushed, true);
            app.SaveROIButton.Text = 'Save ROI';
            app.MirrorROIButton = uibutton(app.ROIBuilderUIFigure, 'push');
            app.MirrorROIButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorROIButtonPushed, true);
            app.MirrorROIButton.Text = 'Mirror ROI';
            app.MirrorinclradiusmmEditFieldLabel = uilabel(app.ROIBuilderUIFigure);
            app.MirrorinclradiusmmEditFieldLabel.Text = {'Mirror incl. '; 'radius (mm)'};
            app.MirrorinclradiusmmEditField = uieditfield(app.ROIBuilderUIFigure, 'numeric');
            app.MirrorinclradiusmmEditField.Value = 2;
            app.ClearROIButton = uibutton(app.ROIBuilderUIFigure, 'push');
            app.ClearROIButton.ButtonPushedFcn = createCallbackFcn(app, @ClearROIButtonPushed, true);
            app.ClearROIButton.Text = 'Clear ROI';
            app.ViewButtonGroup = uibuttongroup(app.ROIBuilderUIFigure);
            app.ViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ROIParcelToggle, true);
            app.ViewButtonGroup.Title = 'View';
            app.ShowROIButton = uiradiobutton(app.ViewButtonGroup);
            app.ShowROIButton.Text = 'Show ROI';
            app.ShowROIButton.Value = true;
            app.ShowParcelsButton = uiradiobutton(app.ViewButtonGroup);
            app.ShowParcelsButton.Text = 'Show Parcels';
            app.MaxgoodSDSmmEditField_2Label = uilabel(app.ROIBuilderUIFigure);
            app.MaxgoodSDSmmEditField_2Label.Text = 'Max. good SDS (mm)  ';
            app.MaxgoodSDSmmEditField = uieditfield(app.ROIBuilderUIFigure, 'numeric');
            app.MaxgoodSDSmmEditField.Value = 30;
            % Show the figure after all components are created
            app.ROIBuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIBuilderAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROIBuilderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIBuilderUIFigure)
        end
    end
end